classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FISSelectionUIFigure            matlab.ui.Figure
        RequisitosPanel                 matlab.ui.container.Panel
        NivelEscolarEditFieldLabel      matlab.ui.control.Label
        NivelEscolarEditField           matlab.ui.control.NumericEditField
        EdadEditFieldLabel              matlab.ui.control.Label
        EdadEditField                   matlab.ui.control.NumericEditField
        NPRequisitosEditField           matlab.ui.control.NumericEditField
        RequisitosEspecialesPanel       matlab.ui.container.Panel
        EspecialidadEditFieldLabel      matlab.ui.control.Label
        EspecialidadEditField           matlab.ui.control.NumericEditField
        EstabilidadAosEditFieldLabel    matlab.ui.control.Label
        EstabilidadAosEditField         matlab.ui.control.NumericEditField
        ExperienciaAosEditFieldLabel    matlab.ui.control.Label
        ExperienciaAosEditField         matlab.ui.control.NumericEditField
        NPRequisitosEspEditField        matlab.ui.control.NumericEditField
        TabGroup                        matlab.ui.container.TabGroup
        NiveldeSeleccinTab              matlab.ui.container.Tab
        UIAxesSeleccion                 matlab.ui.control.UIAxes
        NiveldeSeleccinEditFieldLabel   matlab.ui.control.Label
        NPSeleccionEditField            matlab.ui.control.NumericEditField
        GradosdePertenenciaLabel        matlab.ui.control.Label
        PocoaptoGaugeLabel              matlab.ui.control.Label
        PocoaptoGauge                   matlab.ui.control.SemicircularGauge
        NoaptoGaugeLabel                matlab.ui.control.Label
        NoaptoGauge                     matlab.ui.control.SemicircularGauge
        TextArea                        matlab.ui.control.TextArea
        AptoGaugeLabel                  matlab.ui.control.Label
        AptoGauge                       matlab.ui.control.SemicircularGauge
        PocoaptoEditField               matlab.ui.control.NumericEditField
        NoaptoEditField                 matlab.ui.control.NumericEditField
        AptoEditField                   matlab.ui.control.NumericEditField
        RequisitosTab                   matlab.ui.container.Tab
        UIAxesNivelEscolar              matlab.ui.control.UIAxes
        UIAxesEdad                      matlab.ui.control.UIAxes
        UIAxesRequisitos                matlab.ui.control.UIAxes
        RequisitosEspecialesTab         matlab.ui.container.Tab
        UIAxesEspecialidad              matlab.ui.control.UIAxes
        UIAxesEstabilidad               matlab.ui.control.UIAxes
        UIAxesExperiencia               matlab.ui.control.UIAxes
        UIAxesRequisitosEsp             matlab.ui.control.UIAxes
        HabilidadesConductivasTab       matlab.ui.container.Tab
        UIAxesActitudServicio           matlab.ui.control.UIAxes
        UIAxesIniciativa                matlab.ui.control.UIAxes
        UIAxesManejoConflicto           matlab.ui.control.UIAxes
        UIAxesConductivas               matlab.ui.control.UIAxes
        HabilidadesOrganizacionalesTab  matlab.ui.container.Tab
        UIAxesResponsabilidad           matlab.ui.control.UIAxes
        UIAxesTrabajoEquipo             matlab.ui.control.UIAxes
        UIAxesComunicacion              matlab.ui.control.UIAxes
        UIAxesOrganizacionales          matlab.ui.control.UIAxes
        HabilidadesConductivasPanel     matlab.ui.container.Panel
        ActituddeservicioEditFieldLabel  matlab.ui.control.Label
        ActituddeservicioEditField      matlab.ui.control.NumericEditField
        IniciativaEditFieldLabel        matlab.ui.control.Label
        IniciativaEditField             matlab.ui.control.NumericEditField
        ManejodeconflictoEditFieldLabel  matlab.ui.control.Label
        ManejodeconflictoEditField      matlab.ui.control.NumericEditField
        NPConductivasEditField          matlab.ui.control.NumericEditField
        HabilidadesOrganizacionalesPanel  matlab.ui.container.Panel
        ResponsabilidadEditFieldLabel   matlab.ui.control.Label
        ResponsabilidadEditField        matlab.ui.control.NumericEditField
        TrabajoenequipoEditFieldLabel   matlab.ui.control.Label
        TrabajoenequipoEditField        matlab.ui.control.NumericEditField
        ComunicacinEditFieldLabel       matlab.ui.control.Label
        ComunicacinEditField            matlab.ui.control.NumericEditField
        NPOrganizacionalesEditField     matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        fisRequisitos = readfis('Requisitos');
        fisRequisitosEsp = readfis('RequisitosEsp');
        fisConductivas = readfis('Conductivas');
        fisOrganizacionales = readfis('Organizacionales');
        fisSeleccion = readfis('Seleccion');
        mf = [fismf("trapmf",[0 0 3 4],'Name',"No apto");
                  fismf("trimf",[3 5 7],'Name',"Poco apto");
                  fismf("trapmf",[6 7 10 10],'Name',"Apto")];
    end
    
    methods (Access = private)
        
        function plotNivelEscolar(app)
            [xOut,yOut] = plotmf(app.fisRequisitos,'input',1);
            plot(app.UIAxesNivelEscolar,xOut,yOut,'DisplayName','yOut')
        end
        
        function plotPertenenciaEscolar(app, value)
            dibujarLineaPertenencia(app, app.UIAxesNivelEscolar, value)
            legend(app.UIAxesNivelEscolar, 'Bachillerato', 'Técnico', 'Universitario', 'Posgrado', 'Grados de pertenencia')
        end
        
        function plotEdad(app)
            [xOut,yOut] = plotmf(app.fisRequisitos,'input',2);        
            plot(app.UIAxesEdad,xOut,yOut,'DisplayName','yOut')            
        end
        
        function plotPertenenciaEdad(app, value)
            dibujarLineaPertenencia(app, app.UIAxesEdad, value)
            legend(app.UIAxesEdad, 'Muy joven', 'Joven', 'Adunto joven', 'Adulto', 'Muy adulto', 'Grados de pertenencia')
        end
        
        function plotRequisitos(app)
            [xOut,yOut] = plotmf(app.fisRequisitos,'output',1);        
            plot(app.UIAxesRequisitos,xOut,yOut,'DisplayName','yOut')            
        end
        
        function plotPertenenciaRequisitos(app, value)
            dibujarLineaPertenencia(app, app.UIAxesRequisitos, value)
            legend(app.UIAxesRequisitos, 'Poco adecuado', 'Adecuado', 'Muy adecuado', 'Grados de pertenencia')
        end
        
        function plotEspecialidad(app)
            [xOut,yOut] = plotmf(app.fisRequisitosEsp,'input',1);
            plot(app.UIAxesEspecialidad,xOut,yOut,'DisplayName','yOut')
        end
         
        function plotPertenenciaEspecialidad(app, value)
            dibujarLineaPertenencia(app, app.UIAxesEspecialidad, value)
            legend(app.UIAxesEspecialidad, 'No Confiable', 'Muy Confiable', 'Confiable', 'Grados de pertenencia')
        end
         
        function plotEstabilidad(app)
            [xOut,yOut] = plotmf(app.fisRequisitosEsp,'input',2);       
            plot(app.UIAxesEstabilidad,xOut,yOut,'DisplayName','yOut')            
            
        end
        
        function plotPertenenciaEstabilidad(app, value)
            dibujarLineaPertenencia(app, app.UIAxesEstabilidad, value)
            legend(app.UIAxesEstabilidad, 'No Confiable', 'Muy Confiable', 'Confiable', 'Grados de pertenencia')
        end
          
        function plotExperiencia(app)
            [xOut,yOut] = plotmf(app.fisRequisitosEsp,'input',3);        
            plot(app.UIAxesExperiencia,xOut,yOut,'DisplayName','yOut')            
        end
        
        function plotPertenenciaExperiencia(app, value)
            dibujarLineaPertenencia(app, app.UIAxesExperiencia, value)
            legend(app.UIAxesExperiencia, 'Nada', 'Mucha', 'Poca', 'Suficiente',  'Grados de pertenencia')
        end
           
        function plotRequisitosEsp(app)
            [xOut,yOut] = plotmf(app.fisRequisitosEsp,'output',1);
            plot(app.UIAxesRequisitosEsp,xOut,yOut,'DisplayName','yOut')
        end
            
        function plotPertenenciaRequisitosEsp(app, value)
            dibujarLineaPertenencia(app, app.UIAxesRequisitosEsp, value)
            legend(app.UIAxesRequisitosEsp, 'No Confiable', 'Poco Confiable', 'Confiable', 'Muy Confiable', 'Grados de pertenencia')
        end
            
        function plotActitudServicio(app)
            [xOut,yOut] = plotmf(app.fisConductivas,'input',1);        
            plot(app.UIAxesActitudServicio,xOut,yOut,'DisplayName','yOut')
        end
             
        function plotPertenenciaServicio(app, value)
            dibujarLineaPertenencia(app, app.UIAxesActitudServicio, value)
            legend(app.UIAxesActitudServicio, 'Bajo', 'Promedio', 'Alto', 'Grado de pertenencia')
        end
             
        function plotIniciativa(app)
            [xOut,yOut] = plotmf(app.fisConductivas,'input',2);
            plot(app.UIAxesIniciativa,xOut,yOut,'DisplayName','yOut')
        end
        
        function plotPertenenciaIniciativa(app, value)
            dibujarLineaPertenencia(app, app.UIAxesIniciativa, value)
            legend(app.UIAxesIniciativa, 'Bajo', 'Promedio', 'Alto', 'Grado de pertenencia')
        end
              
        function plotConflicto(app)
            [xOut,yOut] = plotmf(app.fisConductivas,'input',3);       
            plot(app.UIAxesManejoConflicto,xOut,yOut,'DisplayName','yOut')
        end
        
        function plotPertenenciaConflicto(app, value)
            dibujarLineaPertenencia(app, app.UIAxesManejoConflicto, value)
            legend(app.UIAxesManejoConflicto, 'Bajo', 'Promedio', 'Alto', 'Grado de pertenencia')
        end
        
        function plotConductivas(app)
            [xOut,yOut] = plotmf(app.fisConductivas,'output',1);
            plot(app.UIAxesConductivas,xOut,yOut,'DisplayName','yOut')
        end
        
        function plotPertenenciaConductivas(app, value)
            dibujarLineaPertenencia(app, app.UIAxesConductivas, value)
            legend(app.UIAxesConductivas, 'No Confiable', 'Poco Confiable', 'Confiable', 'Muy Confiable', 'Grado de pertenencia')
        end
        
        function plotResponsabilidad(app)
            [xOut,yOut] = plotmf(app.fisOrganizacionales,'input',1);
            plot(app.UIAxesResponsabilidad,xOut,yOut,'DisplayName','yOut')
        end
        
        function plotPertenenciaResponsabilidad(app, value)
            dibujarLineaPertenencia(app, app.UIAxesResponsabilidad, value)
            legend(app.UIAxesResponsabilidad, 'Bajo', 'Promedio', 'Alto', 'Grado de pertenencia')
        end
         
        function plotTrabajoEquipo(app)
            [xOut,yOut] = plotmf(app.fisOrganizacionales,'input',2);
            plot(app.UIAxesTrabajoEquipo,xOut,yOut,'DisplayName','yOut')
        end
          
        function plotPertenenciaTrabajoEquipo(app, value)
            dibujarLineaPertenencia(app, app.UIAxesTrabajoEquipo, value)
            legend(app.UIAxesTrabajoEquipo, 'Bajo', 'Promedio', 'Alto', 'Grado de pertenencia')
        end
          
        function plotComunicacion(app)
            [xOut,yOut] = plotmf(app.fisOrganizacionales,'input',3);
            plot(app.UIAxesComunicacion,xOut,yOut,'DisplayName','yOut')
        end
        
        function plotPertenenciaComunicacion(app, value)
            dibujarLineaPertenencia(app, app.UIAxesComunicacion, value)
            legend(app.UIAxesComunicacion, 'Bajo', 'Promedio', 'Alto', 'Grado de pertenencia')
        end
           
        function plotOrganizacionales(app)
            [xOut,yOut] = plotmf(app.fisOrganizacionales,'output',1);
            plot(app.UIAxesOrganizacionales,xOut,yOut,'DisplayName','yOut')
        end
        
        function plotPertenenciaOrganizacionales(app, value)
            dibujarLineaPertenencia(app, app.UIAxesOrganizacionales, value)
            legend(app.UIAxesOrganizacionales, 'No Confiable', 'Poco Confiable', 'Confiable', 'Muy Confiable', 'Grado de pertenencia')
        end
                 
        function plotSeleccion(app)
            [xOut,yOut] = plotmf(app.fisSeleccion,'output',1);
            plot(app.UIAxesSeleccion,xOut,yOut,'DisplayName','yOut')
        end
                
        function plotPertenenciaSeleccion(app, value)
            dibujarLineaPertenencia(app, app.UIAxesSeleccion, value)
            legend(app.UIAxesSeleccion, 'No Apto', 'Poco Apto', 'Apto', 'Grado de pertenencia')
            
        end
        
        function dibujarLineaPertenencia(~, axes, value)
            line = findobj(axes, 'Label', '*');
            if ~isempty(line)
                delete(line);
            end  
        
            xline(axes, value, '--r', '*');
        end
        
        function calcularPertenencia(app)
            d = uiprogressdlg(app.FISSelectionUIFigure,'Title','Calculando nivel de selección...',...
                'Indeterminate','on');
            
            requisitos = evalfis(app.fisRequisitos, [app.NivelEscolarEditField.Value, app.EdadEditField.Value]);
            requisitosEsp = evalfis(app.fisRequisitosEsp,[app.EspecialidadEditField.Value, app.EstabilidadAosEditField.Value, app.ExperienciaAosEditField.Value]);
            conductivas = evalfis(app.fisConductivas, [app.ActituddeservicioEditField.Value, app.IniciativaEditField.Value, app.ManejodeconflictoEditField.Value]);
            organizacionales = evalfis(app.fisOrganizacionales, [app.ResponsabilidadEditField.Value, app.TrabajoenequipoEditField.Value, app.ComunicacinEditField.Value]);
            seleccion = evalfis(app.fisSeleccion, [requisitos, requisitosEsp, conductivas, organizacionales]);
            
            app.NPRequisitosEditField.Value = requisitos;
            app.NPRequisitosEspEditField.Value = requisitosEsp;
            app.NPConductivasEditField.Value = conductivas;
            app.NPOrganizacionalesEditField.Value = organizacionales;
            app.NPSeleccionEditField.Value = seleccion;
              
            y = evalmf(app.mf, seleccion);
            
            app.AptoGauge.Value = y(3);
            app.PocoaptoGauge.Value = y(2);
            app.NoaptoGauge.Value = y(1);
            
            app.AptoEditField.Value = y(3);
            app.PocoaptoEditField.Value = y(2);
            app.NoaptoEditField.Value = y(1);
                                   
            plotPertenenciaEscolar(app, app.NivelEscolarEditField.Value);
            plotPertenenciaEdad(app, app.EdadEditField.Value);
            plotPertenenciaRequisitos(app, app.NPRequisitosEditField.Value);
            plotPertenenciaEspecialidad(app, app.EspecialidadEditField.Value);
            plotPertenenciaEstabilidad(app, app.EstabilidadAosEditField.Value);
            plotPertenenciaExperiencia(app, app.ExperienciaAosEditField.Value);
            plotPertenenciaRequisitosEsp(app, app.NPRequisitosEspEditField.Value);
            plotPertenenciaServicio(app, app.ActituddeservicioEditField.Value);
            plotPertenenciaIniciativa(app, app.IniciativaEditField.Value);
            plotPertenenciaConflicto(app, app.ManejodeconflictoEditField.Value);
            plotPertenenciaConductivas(app, app.NPConductivasEditField.Value);
            plotPertenenciaResponsabilidad(app, app.ResponsabilidadEditField.Value);
            plotPertenenciaTrabajoEquipo(app, app.TrabajoenequipoEditField.Value);
            plotPertenenciaComunicacion(app, app.ComunicacinEditField.Value);
            plotPertenenciaOrganizacionales(app, app.NPOrganizacionalesEditField.Value);
            plotPertenenciaSeleccion(app, app.NPSeleccionEditField.Value);
            
            pause(0)
            
            close(d);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            plotNivelEscolar(app)
            plotEdad(app)
            plotRequisitos(app)
            plotEspecialidad(app)
            plotEstabilidad(app)
            plotExperiencia(app)
            plotRequisitosEsp(app)
            plotActitudServicio(app)
            plotIniciativa(app)
            plotConflicto(app)
            plotConductivas(app)
            plotResponsabilidad(app)
            plotTrabajoEquipo(app)
            plotComunicacion(app)
            plotOrganizacionales(app)
            plotSeleccion(app)
            
            calcularPertenencia(app)
        end

        % Callback function
        function CalcularButtonPushed(app, event)
           calcularPertenencia(app)
        end

        % Value changed function: ActituddeservicioEditField, 
        % ComunicacinEditField, EdadEditField, 
        % EspecialidadEditField, EstabilidadAosEditField, 
        % ExperienciaAosEditField, IniciativaEditField, 
        % ManejodeconflictoEditField, NivelEscolarEditField, 
        % ResponsabilidadEditField, TrabajoenequipoEditField
        function NivelEscolarEditFieldValueChanged(app, event)
            calcularPertenencia(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FISSelectionUIFigure = uifigure('Visible', 'off');
            app.FISSelectionUIFigure.Name = 'FIS-Selection';
            app.RequisitosPanel = uipanel(app.FISSelectionUIFigure);
            app.RequisitosPanel.Title = 'Requisitos';
            app.NivelEscolarEditFieldLabel = uilabel(app.RequisitosPanel);
            app.NivelEscolarEditFieldLabel.Text = 'Nivel Escolar';
            app.NivelEscolarEditField = uieditfield(app.RequisitosPanel, 'numeric');
            app.NivelEscolarEditField.Limits = [0 10];
            app.NivelEscolarEditField.ValueChangedFcn = createCallbackFcn(app, @NivelEscolarEditFieldValueChanged, true);
            app.NivelEscolarEditField.Tooltip = {'Nivel de escolaridad (en un rango de 1 a 10)'};
            app.NivelEscolarEditField.Value = 7;
            app.EdadEditFieldLabel = uilabel(app.RequisitosPanel);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.EdadEditField = uieditfield(app.RequisitosPanel, 'numeric');
            app.EdadEditField.Limits = [18 55];
            app.EdadEditField.ValueChangedFcn = createCallbackFcn(app, @NivelEscolarEditFieldValueChanged, true);
            app.EdadEditField.Tooltip = {'Edad en años (En un rango de 18-55 años)'};
            app.EdadEditField.Value = 33;
            app.NPRequisitosEditField = uieditfield(app.RequisitosPanel, 'numeric');
            app.NPRequisitosEditField.Editable = 'off';
            app.NPRequisitosEditField.Tooltip = {'Requisitos (En un rango de 0 a 10)'};
            app.RequisitosEspecialesPanel = uipanel(app.FISSelectionUIFigure);
            app.RequisitosEspecialesPanel.Title = 'Requisitos Especiales';
            app.EspecialidadEditFieldLabel = uilabel(app.RequisitosEspecialesPanel);
            app.EspecialidadEditFieldLabel.Text = 'Especialidad';
            app.EspecialidadEditField = uieditfield(app.RequisitosEspecialesPanel, 'numeric');
            app.EspecialidadEditField.Limits = [0 10];
            app.EspecialidadEditField.ValueChangedFcn = createCallbackFcn(app, @NivelEscolarEditFieldValueChanged, true);
            app.EspecialidadEditField.Tooltip = {'Especialidad (En un rango de 0 a 10)'};
            app.EspecialidadEditField.Value = 8;
            app.EstabilidadAosEditFieldLabel = uilabel(app.RequisitosEspecialesPanel);
            app.EstabilidadAosEditFieldLabel.Text = 'Estabilidad (Años)';
            app.EstabilidadAosEditField = uieditfield(app.RequisitosEspecialesPanel, 'numeric');
            app.EstabilidadAosEditField.Limits = [0 5];
            app.EstabilidadAosEditField.ValueChangedFcn = createCallbackFcn(app, @NivelEscolarEditFieldValueChanged, true);
            app.EstabilidadAosEditField.Tooltip = {'Estabilidad en años (En un rango de 0 a 5 años)'};
            app.EstabilidadAosEditField.Value = 4;
            app.ExperienciaAosEditFieldLabel = uilabel(app.RequisitosEspecialesPanel);
            app.ExperienciaAosEditFieldLabel.Text = 'Experiencia (Años)';
            app.ExperienciaAosEditField = uieditfield(app.RequisitosEspecialesPanel, 'numeric');
            app.ExperienciaAosEditField.Limits = [0 10];
            app.ExperienciaAosEditField.ValueChangedFcn = createCallbackFcn(app, @NivelEscolarEditFieldValueChanged, true);
            app.ExperienciaAosEditField.Tooltip = {'Experiencia en puesto en años (En un rango de 0 a 10 años)'};
            app.ExperienciaAosEditField.Value = 4;
            app.NPRequisitosEspEditField = uieditfield(app.RequisitosEspecialesPanel, 'numeric');
            app.NPRequisitosEspEditField.Editable = 'off';
            app.NPRequisitosEspEditField.Tooltip = {'Requisitos especiales (En un rango de 0 a 10)'};
            app.TabGroup = uitabgroup(app.FISSelectionUIFigure);
            app.NiveldeSeleccinTab = uitab(app.TabGroup);
            app.NiveldeSeleccinTab.Title = 'Nivel de Selección';
            app.UIAxesSeleccion = uiaxes(app.NiveldeSeleccinTab);
            title(app.UIAxesSeleccion, 'Nivel de Selección')
            xlabel(app.UIAxesSeleccion, 'X')
            ylabel(app.UIAxesSeleccion, 'Pertenencia')
            app.NiveldeSeleccinEditFieldLabel = uilabel(app.NiveldeSeleccinTab);
            app.NiveldeSeleccinEditFieldLabel.Text = 'Nivel de Selección';
            app.NPSeleccionEditField = uieditfield(app.NiveldeSeleccinTab, 'numeric');
            app.NPSeleccionEditField.Editable = 'off';
            app.TextArea = uitextarea(app.NiveldeSeleccinTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'El nivel de selección es una medida que varía entre 0 y 10 y que dice aproximadamente qué tan apta es una persona para un cargo, siendo 0 no apto y 10 apto.'};
            app.GradosdePertenenciaLabel = uilabel(app.NiveldeSeleccinTab);
            app.GradosdePertenenciaLabel.Text = 'Grados de Pertenencia';
            app.AptoGaugeLabel = uilabel(app.NiveldeSeleccinTab);
            app.AptoGaugeLabel.Text = 'Apto';
            app.AptoGauge = uigauge(app.NiveldeSeleccinTab, 'semicircular');
            app.AptoGauge.Limits = [0 1];
            app.AptoGauge.MajorTicks = [0 0.5 1];
            app.AptoGauge.MajorTickLabels = {'0', '0.5', '1'};
            app.PocoaptoGaugeLabel = uilabel(app.NiveldeSeleccinTab);
            app.PocoaptoGaugeLabel.Text = 'Poco apto';
            app.PocoaptoGauge = uigauge(app.NiveldeSeleccinTab, 'semicircular');
            app.PocoaptoGauge.Limits = [0 1];
            app.PocoaptoGauge.MajorTicks = [0 0.5 1];
            app.PocoaptoGauge.MajorTickLabels = {'0', '0.5', '1'};
            app.NoaptoGaugeLabel = uilabel(app.NiveldeSeleccinTab);
            app.NoaptoGaugeLabel.Text = 'No apto';
            app.NoaptoGauge = uigauge(app.NiveldeSeleccinTab, 'semicircular');
            app.NoaptoGauge.Limits = [0 1];
            app.NoaptoGauge.MajorTicks = [0 0.5 1];
            app.NoaptoGauge.MajorTickLabels = {'0', '0.5', '1'};
            app.AptoEditField = uieditfield(app.NiveldeSeleccinTab, 'numeric');
            app.AptoEditField.Editable = 'off';
            app.PocoaptoEditField = uieditfield(app.NiveldeSeleccinTab, 'numeric');
            app.PocoaptoEditField.Editable = 'off';
            app.NoaptoEditField = uieditfield(app.NiveldeSeleccinTab, 'numeric');
            app.NoaptoEditField.Editable = 'off';
            app.RequisitosTab = uitab(app.TabGroup);
            app.RequisitosTab.Title = 'Requisitos';
            app.UIAxesNivelEscolar = uiaxes(app.RequisitosTab);
            title(app.UIAxesNivelEscolar, 'Nivel Escolar')
            xlabel(app.UIAxesNivelEscolar, 'X')
            ylabel(app.UIAxesNivelEscolar, 'Pertenencia')
            app.UIAxesEdad = uiaxes(app.RequisitosTab);
            title(app.UIAxesEdad, 'Edad')
            xlabel(app.UIAxesEdad, 'X')
            ylabel(app.UIAxesEdad, 'Pertenencia')
            app.UIAxesRequisitos = uiaxes(app.RequisitosTab);
            title(app.UIAxesRequisitos, 'Requisitos')
            xlabel(app.UIAxesRequisitos, 'X')
            ylabel(app.UIAxesRequisitos, 'Pertenencia')
            app.RequisitosEspecialesTab = uitab(app.TabGroup);
            app.RequisitosEspecialesTab.Title = 'Requisitos Especiales';
            app.UIAxesEspecialidad = uiaxes(app.RequisitosEspecialesTab);
            title(app.UIAxesEspecialidad, 'Especialidad')
            xlabel(app.UIAxesEspecialidad, 'X')
            ylabel(app.UIAxesEspecialidad, 'Pertenencia')
            app.UIAxesEstabilidad = uiaxes(app.RequisitosEspecialesTab);
            title(app.UIAxesEstabilidad, 'Estabilidad (Años)')
            xlabel(app.UIAxesEstabilidad, 'X')
            ylabel(app.UIAxesEstabilidad, 'Pertenencia')
            app.UIAxesExperiencia = uiaxes(app.RequisitosEspecialesTab);
            title(app.UIAxesExperiencia, 'Experiencia (Años)')
            xlabel(app.UIAxesExperiencia, 'X')
            ylabel(app.UIAxesExperiencia, 'Pertenencia')
            app.UIAxesRequisitosEsp = uiaxes(app.RequisitosEspecialesTab);
            title(app.UIAxesRequisitosEsp, 'Requisitos Especiales')
            xlabel(app.UIAxesRequisitosEsp, 'X')
            ylabel(app.UIAxesRequisitosEsp, 'Pertenencia')
            app.HabilidadesConductivasTab = uitab(app.TabGroup);
            app.HabilidadesConductivasTab.Title = 'Habilidades Conductivas';
            app.UIAxesActitudServicio = uiaxes(app.HabilidadesConductivasTab);
            title(app.UIAxesActitudServicio, 'Actitud de Servicio')
            xlabel(app.UIAxesActitudServicio, 'X')
            ylabel(app.UIAxesActitudServicio, 'Pertenencia')
            app.UIAxesIniciativa = uiaxes(app.HabilidadesConductivasTab);
            title(app.UIAxesIniciativa, 'Iniciativa')
            xlabel(app.UIAxesIniciativa, 'X')
            ylabel(app.UIAxesIniciativa, 'Pertenencia')
            app.UIAxesManejoConflicto = uiaxes(app.HabilidadesConductivasTab);
            title(app.UIAxesManejoConflicto, 'Manejo de Conflicto')
            xlabel(app.UIAxesManejoConflicto, 'X')
            ylabel(app.UIAxesManejoConflicto, 'Pertenencia')
            app.UIAxesConductivas = uiaxes(app.HabilidadesConductivasTab);
            title(app.UIAxesConductivas, 'Habilidades Conductivas')
            xlabel(app.UIAxesConductivas, 'X')
            ylabel(app.UIAxesConductivas, 'Pertenencia')
            app.HabilidadesOrganizacionalesTab = uitab(app.TabGroup);
            app.HabilidadesOrganizacionalesTab.Title = 'Habilidades Organizacionales';
            app.UIAxesResponsabilidad = uiaxes(app.HabilidadesOrganizacionalesTab);
            title(app.UIAxesResponsabilidad, 'Responsabilidad')
            xlabel(app.UIAxesResponsabilidad, 'X')
            ylabel(app.UIAxesResponsabilidad, 'Pertenencia')
            app.UIAxesTrabajoEquipo = uiaxes(app.HabilidadesOrganizacionalesTab);
            title(app.UIAxesTrabajoEquipo, 'Trabajo en Equipo')
            xlabel(app.UIAxesTrabajoEquipo, 'X')
            ylabel(app.UIAxesTrabajoEquipo, 'Pertenencia')
            app.UIAxesComunicacion = uiaxes(app.HabilidadesOrganizacionalesTab);
            title(app.UIAxesComunicacion, 'Comunicación')
            xlabel(app.UIAxesComunicacion, 'X')
            ylabel(app.UIAxesComunicacion, 'Pertenencia')
            app.UIAxesOrganizacionales = uiaxes(app.HabilidadesOrganizacionalesTab);
            title(app.UIAxesOrganizacionales, 'Habilidades Organizacionales')
            xlabel(app.UIAxesOrganizacionales, 'X')
            ylabel(app.UIAxesOrganizacionales, 'Pertenencia')
            app.HabilidadesConductivasPanel = uipanel(app.FISSelectionUIFigure);
            app.HabilidadesConductivasPanel.Title = 'Habilidades Conductivas';
            app.ActituddeservicioEditFieldLabel = uilabel(app.HabilidadesConductivasPanel);
            app.ActituddeservicioEditFieldLabel.Text = 'Actitud de servicio';
            app.ActituddeservicioEditField = uieditfield(app.HabilidadesConductivasPanel, 'numeric');
            app.ActituddeservicioEditField.Limits = [0 10];
            app.ActituddeservicioEditField.ValueChangedFcn = createCallbackFcn(app, @NivelEscolarEditFieldValueChanged, true);
            app.ActituddeservicioEditField.Tooltip = {'Actitud de servicio (En un rango de 0 a 10)'};
            app.ActituddeservicioEditField.Value = 9;
            app.IniciativaEditFieldLabel = uilabel(app.HabilidadesConductivasPanel);
            app.IniciativaEditFieldLabel.Text = 'Iniciativa';
            app.IniciativaEditField = uieditfield(app.HabilidadesConductivasPanel, 'numeric');
            app.IniciativaEditField.Limits = [0 10];
            app.IniciativaEditField.ValueChangedFcn = createCallbackFcn(app, @NivelEscolarEditFieldValueChanged, true);
            app.IniciativaEditField.Tooltip = {'Iniciativa (En un rango de 0 a 10)'};
            app.IniciativaEditField.Value = 8;
            app.ManejodeconflictoEditFieldLabel = uilabel(app.HabilidadesConductivasPanel);
            app.ManejodeconflictoEditFieldLabel.Text = 'Manejo de conflicto';
            app.ManejodeconflictoEditField = uieditfield(app.HabilidadesConductivasPanel, 'numeric');
            app.ManejodeconflictoEditField.Limits = [0 10];
            app.ManejodeconflictoEditField.ValueChangedFcn = createCallbackFcn(app, @NivelEscolarEditFieldValueChanged, true);
            app.ManejodeconflictoEditField.Tooltip = {'Manejo de conflicto (En un rango de 0 a 10)'};
            app.ManejodeconflictoEditField.Value = 10;
            app.NPConductivasEditField = uieditfield(app.HabilidadesConductivasPanel, 'numeric');
            app.NPConductivasEditField.Editable = 'off';
            app.NPConductivasEditField.Tooltip = {'Habilidades conductivas (En un rango de 0 a 10)'};
            app.HabilidadesOrganizacionalesPanel = uipanel(app.FISSelectionUIFigure);
            app.HabilidadesOrganizacionalesPanel.Title = 'Habilidades Organizacionales';
            app.ResponsabilidadEditFieldLabel = uilabel(app.HabilidadesOrganizacionalesPanel);
            app.ResponsabilidadEditFieldLabel.Text = 'Responsabilidad';
            app.ResponsabilidadEditField = uieditfield(app.HabilidadesOrganizacionalesPanel, 'numeric');
            app.ResponsabilidadEditField.Limits = [0 10];
            app.ResponsabilidadEditField.ValueChangedFcn = createCallbackFcn(app, @NivelEscolarEditFieldValueChanged, true);
            app.ResponsabilidadEditField.Tooltip = {'Responsabilidad y compromiso (En un rango de 0 a 10)'};
            app.ResponsabilidadEditField.Value = 7;
            app.TrabajoenequipoEditFieldLabel = uilabel(app.HabilidadesOrganizacionalesPanel);
            app.TrabajoenequipoEditFieldLabel.Text = 'Trabajo en equipo';
            app.TrabajoenequipoEditField = uieditfield(app.HabilidadesOrganizacionalesPanel, 'numeric');
            app.TrabajoenequipoEditField.Limits = [0 10];
            app.TrabajoenequipoEditField.ValueChangedFcn = createCallbackFcn(app, @NivelEscolarEditFieldValueChanged, true);
            app.TrabajoenequipoEditField.Tooltip = {'Trabajo en equipo (En un rango de 0 a 10)'};
            app.TrabajoenequipoEditField.Value = 8;
            app.ComunicacinEditFieldLabel = uilabel(app.HabilidadesOrganizacionalesPanel);
            app.ComunicacinEditFieldLabel.Text = 'Comunicación';
            app.ComunicacinEditField = uieditfield(app.HabilidadesOrganizacionalesPanel, 'numeric');
            app.ComunicacinEditField.Limits = [0 10];
            app.ComunicacinEditField.ValueChangedFcn = createCallbackFcn(app, @NivelEscolarEditFieldValueChanged, true);
            app.ComunicacinEditField.Tooltip = {'Comunicación efectiva (En un rango de 0 a10)'};
            app.ComunicacinEditField.Value = 9;
            app.NPOrganizacionalesEditField = uieditfield(app.HabilidadesOrganizacionalesPanel, 'numeric');
            app.NPOrganizacionalesEditField.Editable = 'off';
            app.NPOrganizacionalesEditField.Tooltip = {'Habilidades organizacionales (En un rango de 0 a 10)'};
            % Show the figure after all components are created
            app.FISSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FISSelectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FISSelectionUIFigure)
        end
    end
end